classdef MRI_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRIRegistrationToolUIFigure  matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PathEditFieldLabel           matlab.ui.control.Label
        PathEditField                matlab.ui.control.EditField
        PleaseenterthefolderpathforyourfilesLabel  matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
        PleaseselectallfilesthatyouwishtoregisterLabel  matlab.ui.control.Label
        SelectButton                 matlab.ui.control.Button
        FilesListBoxLabel            matlab.ui.control.Label
        FilesListBox                 matlab.ui.control.ListBox
        StatusEditFieldLabel         matlab.ui.control.Label
        StatusEditField              matlab.ui.control.EditField
        FPSSliderLabel               matlab.ui.control.Label
        FPSSlider                    matlab.ui.control.Slider
        PlayMovieButton              matlab.ui.control.Button
    end


    properties (Access = private)
        folderpath % folderpath string
        image_movie % movie to play
        movie_flag=0; %does the movie exist?
    end


    methods (Access = private)

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.StatusEditField.Value;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            %We want to make sure the folder isn't empty
            %Then display all the dicom files in the folder
            app.folderpath=app.PathEditField.Value;
            filelist=ls(app.folderpath);
            if length(filelist)==0 %Make sure folder is not empty
                app.StatusEditField.Value='Double check that you have entered in the correct path';
                app.FilesListBox.Items={};
            else
                app.StatusEditField.Value='';
                filelist_length=length(filelist(:,1));
                dcm_counter=1;
                for k=1:filelist_length
                    [pth,name,extension]=fileparts(filelist(k,:));
                    extension=strtrim(extension);                
                    if strcmp(extension,'.dcm') || strcmp(extension,'.IMA')
                        temp(dcm_counter)={filelist(k,:)}; %K=1,2 is '.' and '..'
                        temp(dcm_counter)=strtrim(temp(dcm_counter));
                        dcm_counter=dcm_counter+1;
                    end
                end
                
                if dcm_counter>1
                    app.FilesListBox.Items=temp;
                    app.StatusEditField.Value='';
                else
                    app.FilesListBox.Items={};
                    app.StatusEditField.Value='This folder does not have .dcm or .ima files';
                end
            end
        end

        % Value changed function: FPSSlider
        function FPSSliderValueChanged(app, event)
            %The slider is used to select movie frames per second
            value = app.FPSSlider.Value;
            
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            %The list box displays selectable files
            value = app.FilesListBox.Value;
        end

        % Button pushed function: PlayMovieButton
        function PlayMovieButtonPushed(app, event)
            %If the movie exists, play it 
            if app.movie_flag==1
                implay(app.image_movie,app.FPSSlider.Value);
                app.StatusEditField.Value='';
            else
                app.StatusEditField.Value='There is no movie to play';
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            %This will call the bulk of functions
            %value is given in order selected
            %we want to display everything in chronological order
            %so we use a struct to keep track of acquisition time
            %and then sort the files by time
            value = app.FilesListBox.Value;
            num_selected=length(value);
            if num_selected > 0
                app.StatusEditField.Value='';
                folderpath_concat=strcat(app.folderpath,'\');
                for k=1:num_selected
                   temp1=char(value(:,k));
                   temp=strcat(folderpath_concat,temp1);
                   dcm_info=struct('file',dicomread(temp), 'info',dicominfo(temp),'time',[],'name',k);
                   dcm_info.time=str2double(dcm_info.info.AcquisitionTime);
                   dcm_list(k,:)=dcm_info;
                end          
                %next 6 lines modified code borrowed from mathworks support staff
                cells=struct2cell(dcm_list);
                sortvals=cells(3,:,:)
                mat = cell2mat(sortvals); % converts values to a matrix
                mat = squeeze(mat); %removes the empty dimensions for a single vector
                [sorted,ix] = sort(mat); %sorts the vector of values
                dcm_list=dcm_list(ix)
                for k=1:num_selected
                    dicom_list(k,:,:)=dcm_list(k).file;
                end
                
                %Identify a region of interest
                ref_index=ceil(num_selected/2);
                Iref=squeeze(dicom_list(ref_index,:,:));
                %A separate figure needs to pop up
                dicomshow(Iref);
                title({'Please draw continuous Region of Interest on the image with your cursor, then click X to exit image.'});
                Mask = uint16(createMask(imfreehand));
                hold on;
                size(squeeze(dicom_list(1,:,:)))
                
                %PASS DICOM FILES TO REGISTRATION FUNCTIONS
                %PROCESS DICOM FILES
                for k=1:num_selected
                    Ireg = squeeze(dicom_list(k,:,:));
                    Ireg_G = GlobalMotionEstimation(Mask,Iref,Ireg);
                    dicom_list(k,:,:) = LocalMotionEstimation(Ireg_G, Iref, Mask);
                end
                dicom_list(ref_index,:,:) = Iref;
                %.................
                %.................
                %YAY! FINISHED REGISTERING 
                %NOW DISPLAY MONTAGE
                
                X=[];
                for k=1:num_selected
                    %.....................
                    %.......................
                    %Once main code is implemented
                    %uncomment regd_images
                    %.......................
                    %..........................
                    %regd_images is the array of registered images
                    %adjust grayscale values so that they can be displayed
                    dicom_adjust(k,:,:)=adjustgray(squeeze(dicom_list(k,:,:)));
                    alpha=squeeze(dicom_adjust(k,:,:));
                    %regd_images(k,:,:)=adjustgray(regd_images(k,:,:));
                    %X=cat(4,X,regd_images(k,:,:));
                    X=cat(4,X,alpha); 
                    %X is a 176x176x1xnum_selected
                end
                montage(X,gray(256),'Parent',app.UIAxes)
                %setup movie to display the registration process
                app.image_movie=immovie(X,gray(256));
                app.movie_flag=1;
            else
                app.StatusEditField.Value='There is nothing to select';
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRIRegistrationToolUIFigure = uifigure;
            app.MRIRegistrationToolUIFigure.Name = 'MRI Registration Tool';
            app.UIAxes = uiaxes(app.MRIRegistrationToolUIFigure);
            title(app.UIAxes, 'Montage of Registered Images')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = '';
            app.PathEditFieldLabel = uilabel(app.MRIRegistrationToolUIFigure);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.MRIRegistrationToolUIFigure, 'text');
            app.PleaseenterthefolderpathforyourfilesLabel = uilabel(app.MRIRegistrationToolUIFigure);
            app.PleaseenterthefolderpathforyourfilesLabel.Text = 'Please enter the folder path for your files';
            app.EnterButton = uibutton(app.MRIRegistrationToolUIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.PleaseselectallfilesthatyouwishtoregisterLabel = uilabel(app.MRIRegistrationToolUIFigure);
            app.PleaseselectallfilesthatyouwishtoregisterLabel.Text = 'Please select all files that you wish to register';
            app.SelectButton = uibutton(app.MRIRegistrationToolUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.FilesListBoxLabel = uilabel(app.MRIRegistrationToolUIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.MRIRegistrationToolUIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.StatusEditFieldLabel = uilabel(app.MRIRegistrationToolUIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.MRIRegistrationToolUIFigure, 'text');
            app.FPSSliderLabel = uilabel(app.MRIRegistrationToolUIFigure);
            app.FPSSliderLabel.Text = 'FPS';
            app.FPSSlider = uislider(app.MRIRegistrationToolUIFigure);
            app.FPSSlider.Limits = [1 10];
            app.FPSSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FPSSlider.ValueChangedFcn = createCallbackFcn(app, @FPSSliderValueChanged, true);
            app.FPSSlider.Value = 1;
            app.PlayMovieButton = uibutton(app.MRIRegistrationToolUIFigure, 'push');
            app.PlayMovieButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMovieButtonPushed, true);
            app.PlayMovieButton.Text = 'Play Movie';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MRI_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRIRegistrationToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRIRegistrationToolUIFigure)
        end
    end
end